classdef stimScriptMaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        LARPe                           matlab.ui.control.ListBox
        LARPButton                      matlab.ui.control.StateButton
        RALPe                           matlab.ui.control.ListBox
        RALPButton                      matlab.ui.control.StateButton
        LHRHe                           matlab.ui.control.ListBox
        LHRHButton                      matlab.ui.control.StateButton
        CCe                             matlab.ui.control.ListBox
        De                              matlab.ui.control.ListBox
        CommonCrusLabel                 matlab.ui.control.Label
        DistantLabel                    matlab.ui.control.Label
        AnimalDropDownLabel             matlab.ui.control.Label
        AnimalDropDown                  matlab.ui.control.DropDown
        ReturnEOptionsPanel             matlab.ui.container.Panel
        BipolarCheckBox                 matlab.ui.control.CheckBox
        CommonCrusCheckBox              matlab.ui.control.CheckBox
        DistantCheckBox                 matlab.ui.control.CheckBox
        RecordingSystemButtonGroup      matlab.ui.container.ButtonGroup
        DigitalCoilButton               matlab.ui.control.RadioButton
        CEDSpike2Button                 matlab.ui.control.RadioButton
        StimulationBlockParametersPanel  matlab.ui.container.Panel
        DurationmSEditFieldLabel        matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        GapmSEditFieldLabel             matlab.ui.control.Label
        GapEditField                    matlab.ui.control.NumericEditField
        RateppsEditFieldLabel           matlab.ui.control.Label
        RateppsEditField                matlab.ui.control.NumericEditField
        CyclesEditFieldLabel            matlab.ui.control.Label
        CyclesEditField                 matlab.ui.control.NumericEditField
        StimulationTypeButtonGroup      matlab.ui.container.ButtonGroup
        SymmetricPulseTrainsButton      matlab.ui.control.RadioButton
        AsymmetricPulseTrainsButton     matlab.ui.control.RadioButton
        SinusoidalButton                matlab.ui.control.RadioButton
        MotionModulatedButton           matlab.ui.control.RadioButton
        SymmetricPulseTrainsPanel       matlab.ui.container.Panel
        PhaseDurationuSEditFieldLabel   matlab.ui.control.Label
        PhaseDurationEditField          matlab.ui.control.NumericEditField
        InterphaseGapuSEditFieldLabel   matlab.ui.control.Label
        InterphaseGapEditField          matlab.ui.control.NumericEditField
        CurrentAmpMinuALabel            matlab.ui.control.Label
        CurrentAmpMin                   matlab.ui.control.NumericEditField
        CurrentAmpMaxEditFieldLabel     matlab.ui.control.Label
        CurrentAmpMax                   matlab.ui.control.NumericEditField
        CurrentByStepsofLabel           matlab.ui.control.Label
        CurrentSteps                    matlab.ui.control.NumericEditField
        AsymmetricPulseTrainsPanel      matlab.ui.container.Panel
        Phase1DurationuSEditFieldLabel  matlab.ui.control.Label
        Phase1DurationEditField         matlab.ui.control.NumericEditField
        Phase1AmpMinuALabel             matlab.ui.control.Label
        Phase1AmpEditField              matlab.ui.control.NumericEditField
        Phase2DurationuSEditFieldLabel  matlab.ui.control.Label
        Phase2DurationuSEditField       matlab.ui.control.NumericEditField
        Phase2AmpuAEditFieldLabel       matlab.ui.control.Label
        Phase2AmpEditField              matlab.ui.control.NumericEditField
        Phase1AmpMaxuAEditFieldLabel    matlab.ui.control.Label
        Phase1AmpMaxEditField           matlab.ui.control.NumericEditField
        NumberofAmpStepsEditFieldLabel  matlab.ui.control.Label
        NumberofAmpStepsEditField       matlab.ui.control.NumericEditField
        TeensyCOMEditFieldLabel         matlab.ui.control.Label
        TeensyCOMEditField              matlab.ui.control.EditField
        KeithlyCOMEditFieldLabel        matlab.ui.control.Label
        KeithlyCOMEditField             matlab.ui.control.EditField
        stimblock                       matlab.ui.control.UIAxes
        DelaySEditFieldLabel            matlab.ui.control.Label
        DelaySEditField                 matlab.ui.control.NumericEditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        GenerateFileButton              matlab.ui.control.Button
        SavePathButton                  matlab.ui.control.Button
        currAmp                         matlab.ui.control.Table
        stimpulse                       matlab.ui.control.UIAxes
        asymAmp                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        currentList = []; % Description
        phase1CurrentList = [];
        phase2CurrentList = [];
        symmCurrentAmpMin = 0;
        symmCurrentAmpMax = 0;
        symmCurrentAmpSteps = 0;
        clicktype = ''; % Description
        
    end
    
    methods (Access = private)
        
        function PlotStimBlock(app)
            if app.SymmetricPulseTrainsButton
                
            elseif app.AsymmetricPulseTrainsButton
            elseif app.SinusoidalButton
            elseif app.MotionModulatedButton
            end
        end
        
        function currentList = calcSymmCurrent(app)
        currentList = [app.CurrentAmpMin.Value:app.CurrentSteps.Value:app.CurrentAmpMax.Value]';
        app.currAmp.Data = currentList;
        end
        
        function app = removeCurrentVals(app)
            if app.SymmetricPulseTrainsButton
                app.currentList(isnan(app.currentList))=[];
                app.currAmp.Data = app.currentList;
            elseif app.AsymmetricPulseTrainsButton
                app.phase1CurrentList(isnan(app.phase1CurrentList))=[];
                app.phase2CurrentList(isnan(app.phase2CurrentList))=[];
                app.asymAmp.Data(1) = app.phase1CurrentList;
                app.asymAmp.Data(2) = app.phase2CurrentList;
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.currAmp.Data = [10:10:100]';
        end

        % Value changed function: AnimalDropDown
        function AnimalDropDownValueChanged(app, event)
            value = app.AnimalDropDown.Value;
            switch value
                case 'GiGi'
                    app.LARPe.Value = {'4' '5' '6'};
                    app.RALPe.Value = {'1' '2' '3'};
                    app.LHRHe.Value = {'7' '8' '9'};
                    app.CCe.Value = {'11'};
                    app.De.Value = {'10'};
                case 'MoMo'
                    app.LARPe.Value = {'7' '8' '9'};
                    app.RALPe.Value = {'1' '2' '3'};
                    app.LHRHe.Value = {'4' '5' '6'};
                    app.CCe.Value = {'11'};
                    app.De.Value = {'10'};
                case 'Nancy'
                case 'Yoda'
                    app.LARPe.Value = {'1' '2' '3'};
                    app.RALPe.Value = {'4' '5' '6' '14'};
                    app.LHRHe.Value = {'7' '8' '9' '15'};
                    app.CCe.Value = {'11' '12' '13'};
                    app.De.Value = {'10'};
            end
        end

        % Selection changed function: StimulationTypeButtonGroup
        function StimulationTypeButtonGroupSelectionChanged(app, event)

            selectedButton = app.StimulationTypeButtonGroup.SelectedObject.Text
            switch selectedButton
                case 'Symmetric Pulse Trains'
                    app.AsymmetricPulseTrainsPanel.Visible = 0;
                    app.SymmetricPulseTrainsPanel.Visible = 1;
                    app.asymAmp.Visible = 0;
                    app.currAmp.Visible = 1;
                case 'Asymmetric Pulse Trains'
                    app.AsymmetricPulseTrainsPanel.Visible = 1;
                    app.SymmetricPulseTrainsPanel.Visible = 0;
                    app.asymAmp.Visible = 1;
                    app.currAmp.Visible = 0;
                case 'Sinusoidal'
                case 'Motion Modulated'
            end
            
        end

        % Button pushed function: SavePathButton
        function SavePathButtonPushed(app, event)
            saveDir = uigetdir(path,'Choose directory to save file');
            cd(saveDir)
            app.SavePathButton.Text = saveDir;
        end

        % Button pushed function: GenerateFileButton
        function GenerateFileButtonPushed(app, event)
            stimList = [];
            refList = [];
            if app.LARPButton.Value
                stimList = [stimList app.LARPe.Value]
            end
            if app.RALPButton.Value
                stimList = [stimList app.RALPe.Value]
            end
            if app.LHRHButton.Value
                stimList = [stimList app.LHRHe.Value]
            end
            
        end

        % Value changed function: CurrentAmpMin
        function CurrentAmpMinValueChanged(app, event)
        app.symmCurrentAmpMin = 1;
        if app.symmCurrentAmpMin + app.symmCurrentAmpMax + app.symmCurrentAmpSteps == 3
            [app.currentList] = calcSymmCurrent(app);
        end
            
        end

        % Value changed function: CurrentAmpMax
        function CurrentAmpMaxValueChanged(app, event)
            app.symmCurrentAmpMax = 1;
        if app.symmCurrentAmpMin + app.symmCurrentAmpMax + app.symmCurrentAmpSteps == 3
            [app.currentList] = calcSymmCurrent(app);
        end
        end

        % Value changed function: CurrentSteps
        function CurrentStepsValueChanged(app, event)
            app.symmCurrentAmpSteps = 1;
        if app.symmCurrentAmpMin + app.symmCurrentAmpMax + app.symmCurrentAmpSteps == 3
            [app.currentList] = calcSymmCurrent(app);
        end
        end

        % Cell selection callback: currAmp
        function currAmpCellSelection(app, event)
        app.UIFigure.SelectionType    
        event.Source
            indices = event.Indices
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        %currAmpCellSelection(app, event)
        end

        % Display data changed function: currAmp
        function currAmpDisplayDataChanged(app, event)
            newDisplayData = app.currAmp.DisplayData;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
event.Source
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Generate Stimulation Scripts to Use with the Keithley/Crosspoint Program';
            app.LARPe = uilistbox(app.UIFigure);
            app.LARPe.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.LARPe.Multiselect = 'on';
            app.LARPe.Value = {'4', '5', '6'};
            app.LARPButton = uibutton(app.UIFigure, 'state');
            app.LARPButton.Text = 'LARP';
            app.RALPe = uilistbox(app.UIFigure);
            app.RALPe.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.RALPe.Multiselect = 'on';
            app.RALPe.Value = {'1', '2', '3'};
            app.RALPButton = uibutton(app.UIFigure, 'state');
            app.RALPButton.Text = 'RALP';
            app.LHRHe = uilistbox(app.UIFigure);
            app.LHRHe.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.LHRHe.Multiselect = 'on';
            app.LHRHe.Value = {'7', '8', '9'};
            app.LHRHButton = uibutton(app.UIFigure, 'state');
            app.LHRHButton.Text = 'LHRH';
            app.CCe = uilistbox(app.UIFigure);
            app.CCe.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.CCe.Multiselect = 'on';
            app.CCe.Value = {'11'};
            app.De = uilistbox(app.UIFigure);
            app.De.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.De.Multiselect = 'on';
            app.De.Value = {'10'};
            app.CommonCrusLabel = uilabel(app.UIFigure);
            app.CommonCrusLabel.Text = {'Common '; 'Crus'};
            app.DistantLabel = uilabel(app.UIFigure);
            app.DistantLabel.Text = 'Distant';
            app.AnimalDropDownLabel = uilabel(app.UIFigure);
            app.AnimalDropDownLabel.Text = 'Animal';
            app.AnimalDropDown = uidropdown(app.UIFigure);
            app.AnimalDropDown.Items = {'GiGi', 'MoMo', 'Nancy', 'Yoda'};
            app.AnimalDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalDropDownValueChanged, true);
            app.AnimalDropDown.Value = 'GiGi';
            app.ReturnEOptionsPanel = uipanel(app.UIFigure);
            app.ReturnEOptionsPanel.Title = 'Return E. Options';
            app.BipolarCheckBox = uicheckbox(app.ReturnEOptionsPanel);
            app.BipolarCheckBox.Text = 'Bipolar';
            app.CommonCrusCheckBox = uicheckbox(app.ReturnEOptionsPanel);
            app.CommonCrusCheckBox.Text = 'Common Crus';
            app.DistantCheckBox = uicheckbox(app.ReturnEOptionsPanel);
            app.DistantCheckBox.Text = 'Distant';
            app.RecordingSystemButtonGroup = uibuttongroup(app.UIFigure);
            app.RecordingSystemButtonGroup.Title = 'Recording System';
            app.DigitalCoilButton = uiradiobutton(app.RecordingSystemButtonGroup);
            app.DigitalCoilButton.Text = 'Digital Coil';
            app.DigitalCoilButton.Value = true;
            app.CEDSpike2Button = uiradiobutton(app.RecordingSystemButtonGroup);
            app.CEDSpike2Button.Text = 'CED/Spike2';
            app.StimulationBlockParametersPanel = uipanel(app.UIFigure);
            app.StimulationBlockParametersPanel.Title = 'Stimulation Block Parameters';
            app.DurationmSEditFieldLabel = uilabel(app.StimulationBlockParametersPanel);
            app.DurationmSEditFieldLabel.Text = 'Duration (mS)';
            app.DurationEditField = uieditfield(app.StimulationBlockParametersPanel, 'numeric');
            app.GapmSEditFieldLabel = uilabel(app.StimulationBlockParametersPanel);
            app.GapmSEditFieldLabel.Text = 'Gap (mS)';
            app.GapEditField = uieditfield(app.StimulationBlockParametersPanel, 'numeric');
            app.RateppsEditFieldLabel = uilabel(app.StimulationBlockParametersPanel);
            app.RateppsEditFieldLabel.Text = 'Rate (pps)';
            app.RateppsEditField = uieditfield(app.StimulationBlockParametersPanel, 'numeric');
            app.CyclesEditFieldLabel = uilabel(app.StimulationBlockParametersPanel);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.CyclesEditField = uieditfield(app.StimulationBlockParametersPanel, 'numeric');
            app.StimulationTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.StimulationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimulationTypeButtonGroupSelectionChanged, true);
            app.StimulationTypeButtonGroup.Title = 'Stimulation Type';
            app.SymmetricPulseTrainsButton = uiradiobutton(app.StimulationTypeButtonGroup);
            app.SymmetricPulseTrainsButton.Text = 'Symmetric Pulse Trains';
            app.SymmetricPulseTrainsButton.Value = true;
            app.AsymmetricPulseTrainsButton = uiradiobutton(app.StimulationTypeButtonGroup);
            app.AsymmetricPulseTrainsButton.Text = 'Asymmetric Pulse Trains';
            app.SinusoidalButton = uiradiobutton(app.StimulationTypeButtonGroup);
            app.SinusoidalButton.Text = 'Sinusoidal';
            app.MotionModulatedButton = uiradiobutton(app.StimulationTypeButtonGroup);
            app.MotionModulatedButton.Text = 'Motion Modulated';
            app.SymmetricPulseTrainsPanel = uipanel(app.UIFigure);
            app.SymmetricPulseTrainsPanel.Title = 'Symmetric Pulse Trains';
            app.PhaseDurationuSEditFieldLabel = uilabel(app.SymmetricPulseTrainsPanel);
            app.PhaseDurationuSEditFieldLabel.Text = 'Phase Duration (uS)';
            app.PhaseDurationEditField = uieditfield(app.SymmetricPulseTrainsPanel, 'numeric');
            app.InterphaseGapuSEditFieldLabel = uilabel(app.SymmetricPulseTrainsPanel);
            app.InterphaseGapuSEditFieldLabel.Text = 'Interphase Gap (uS)';
            app.InterphaseGapEditField = uieditfield(app.SymmetricPulseTrainsPanel, 'numeric');
            app.CurrentAmpMinuALabel = uilabel(app.SymmetricPulseTrainsPanel);
            app.CurrentAmpMinuALabel.Text = 'Current Amp Min (uA)';
            app.CurrentAmpMin = uieditfield(app.SymmetricPulseTrainsPanel, 'numeric');
            app.CurrentAmpMin.ValueChangedFcn = createCallbackFcn(app, @CurrentAmpMinValueChanged, true);
            app.CurrentAmpMaxEditFieldLabel = uilabel(app.SymmetricPulseTrainsPanel);
            app.CurrentAmpMaxEditFieldLabel.Text = 'Current Amp Max';
            app.CurrentAmpMax = uieditfield(app.SymmetricPulseTrainsPanel, 'numeric');
            app.CurrentAmpMax.ValueChangedFcn = createCallbackFcn(app, @CurrentAmpMaxValueChanged, true);
            app.CurrentByStepsofLabel = uilabel(app.SymmetricPulseTrainsPanel);
            app.CurrentByStepsofLabel.Text = 'Current By Steps of';
            app.CurrentSteps = uieditfield(app.SymmetricPulseTrainsPanel, 'numeric');
            app.CurrentSteps.ValueChangedFcn = createCallbackFcn(app, @CurrentStepsValueChanged, true);
            app.AsymmetricPulseTrainsPanel = uipanel(app.UIFigure);
            app.AsymmetricPulseTrainsPanel.Title = 'Asymmetric Pulse Trains';
            app.Phase1DurationuSEditFieldLabel = uilabel(app.AsymmetricPulseTrainsPanel);
            app.Phase1DurationuSEditFieldLabel.Text = 'Phase 1 Duration (uS)';
            app.Phase1DurationEditField = uieditfield(app.AsymmetricPulseTrainsPanel, 'numeric');
            app.Phase1AmpMinuALabel = uilabel(app.AsymmetricPulseTrainsPanel);
            app.Phase1AmpMinuALabel.Text = 'Phase 1 Amp Min (uA)';
            app.Phase1AmpEditField = uieditfield(app.AsymmetricPulseTrainsPanel, 'numeric');
            app.Phase2DurationuSEditFieldLabel = uilabel(app.AsymmetricPulseTrainsPanel);
            app.Phase2DurationuSEditFieldLabel.Text = 'Phase 2 Duration (uS)';
            app.Phase2DurationuSEditField = uieditfield(app.AsymmetricPulseTrainsPanel, 'numeric');
            app.Phase2AmpuAEditFieldLabel = uilabel(app.AsymmetricPulseTrainsPanel);
            app.Phase2AmpuAEditFieldLabel.Text = 'Phase 2 Amp (uA)';
            app.Phase2AmpEditField = uieditfield(app.AsymmetricPulseTrainsPanel, 'numeric');
            app.Phase1AmpMaxuAEditFieldLabel = uilabel(app.AsymmetricPulseTrainsPanel);
            app.Phase1AmpMaxuAEditFieldLabel.Text = 'Phase 1 Amp Max (uA)';
            app.Phase1AmpMaxEditField = uieditfield(app.AsymmetricPulseTrainsPanel, 'numeric');
            app.NumberofAmpStepsEditFieldLabel = uilabel(app.AsymmetricPulseTrainsPanel);
            app.NumberofAmpStepsEditFieldLabel.Text = 'Number of Amp Steps';
            app.NumberofAmpStepsEditField = uieditfield(app.AsymmetricPulseTrainsPanel, 'numeric');
            app.TeensyCOMEditFieldLabel = uilabel(app.UIFigure);
            app.TeensyCOMEditFieldLabel.Text = 'Teensy COM';
            app.TeensyCOMEditField = uieditfield(app.UIFigure, 'text');
            app.TeensyCOMEditField.Value = 'com33';
            app.KeithlyCOMEditFieldLabel = uilabel(app.UIFigure);
            app.KeithlyCOMEditFieldLabel.Text = 'Keithly COM';
            app.KeithlyCOMEditField = uieditfield(app.UIFigure, 'text');
            app.KeithlyCOMEditField.Value = 'com32';
            app.stimblock = uiaxes(app.UIFigure);
            title(app.stimblock, 'Stimulation Block Shape')
            xlabel(app.stimblock, 'Time (mS)')
            ylabel(app.stimblock, 'Amplitude (uA)')
            app.DelaySEditFieldLabel = uilabel(app.UIFigure);
            app.DelaySEditFieldLabel.Text = 'Delay (S)';
            app.DelaySEditField = uieditfield(app.UIFigure, 'numeric');
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.SavePathButton = uibutton(app.UIFigure, 'push');
            app.SavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SavePathButtonPushed, true);
            app.SavePathButton.Text = 'Save Path';
            app.GenerateFileButton = uibutton(app.UIFigure, 'push');
            app.GenerateFileButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFileButtonPushed, true);
            app.GenerateFileButton.Text = 'Generate File';
            app.currAmp = uitable(app.UIFigure);
            app.currAmp.ColumnName = {'Current Values'};
            app.currAmp.RowName = {};
            app.currAmp.ColumnSortable = true;
            app.currAmp.ColumnEditable = true;
            app.currAmp.CellSelectionCallback = createCallbackFcn(app, @currAmpCellSelection, true);
            app.currAmp.DisplayDataChangedFcn = createCallbackFcn(app, @currAmpDisplayDataChanged, true);
            app.stimpulse = uiaxes(app.UIFigure);
            title(app.stimpulse, 'Stimulation Pulse Shape')
            xlabel(app.stimpulse, 'Time (uS)')
            ylabel(app.stimpulse, 'Amplitude (uA)')
            app.asymAmp = uitable(app.UIFigure);
            app.asymAmp.ColumnName = {'Phase 1 Amplitude'; 'Phase 2 Amplitude'};
            app.asymAmp.RowName = {};
            app.asymAmp.ColumnSortable = true;
            app.asymAmp.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimScriptMaker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end